classdef app_iris_recognization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IrisRecognition    matlab.ui.Figure
        ImageselectButton  matlab.ui.control.Button
        NameTextAreaLabel  matlab.ui.control.Label
        NameTextArea       matlab.ui.control.TextArea
        InfoTextAreaLabel  matlab.ui.control.Label
        InfoTextArea       matlab.ui.control.TextArea
        ViewaccountButton  matlab.ui.control.Button
        EnrollButton       matlab.ui.control.Button
        VerifyButton       matlab.ui.control.Button
        StatusLabel        matlab.ui.control.Label
        IdleLabel          matlab.ui.control.Label
    end


    properties (Access = public)
        im_name = 0; % Name of the selected image
        im_path = 0; % Path of the selected image
        im = 0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('fnc')
            clear
            clc
        end

        % Button pushed function: EnrollButton
        function EnrollButtonPushed(app, event)
            % Check whether the selected image is valid
            if ~app.im_name
                msgbox('No image selected','Error','modal')
                app.IdleLabel.Text = 'No image selected';
            else
            
            % Check whether the Name and Info are typed
            if isempty(app.NameTextArea.Value{1}) || isempty(app.InfoTextArea.Value{1})
                msgbox('Name or Info is not typed','Error','modal')
                app.IdleLabel.Text = 'Name or Info is not typed';
            else
            
            % Extract feature of the iris image
            [template, mask] = extractFeature(app.im);
            
            % Create an account
            createAccount(template, mask, app.NameTextArea.Value, app.InfoTextArea.Value);
            
            % Notify
            msgbox('Account is created successfully','Success','modal')
            app.IdleLabel.Text = 'Enrollment completed';
            end
            end
        end

        % Button pushed function: ImageselectButton
        function ImageselectButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.jpg','Select an iris image...', '../CASIA-database/');
            app.im_name = FileName;
            app.im_path = PathName;
            app.im = [PathName, FileName];
            if FileName
                app.IdleLabel.Text = sprintf('Image %s selected', FileName);
            else
                app.IdleLabel.Text = 'No image selected';
            end
        end

        % Close request function: IrisRecognition
        function IrisRecognitionCloseRequest(app, event)
            delete(app);
            rmpath('fnc')
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.NameTextArea.Value = '';
            app.InfoTextArea.Value = '';
            % Check whether the selected image is valid
            if ~app.im_name
                msgbox('No image selected','Error','modal')
                app.IdleLabel.Text = 'No image selected';
            else
            
            % Extract feature from the selected iris image
            [template_extr, mask_extr] = extractFeature(app.im);
            
            % Matching
            id_acc = matching(template_extr, mask_extr, 0.38);
            
            % Show notification
            if id_acc == -1
                msgbox('An error has occured','Error','modal')
            elseif id_acc == 0
                msgbox('No matched','Warning','modal')
            else
                load(['template-database/', num2str(id_acc), '.mat']);
                app.NameTextArea.Value = name;
                app.InfoTextArea.Value = exinfo;
                msgbox(sprintf('Matched ID: %d', id_acc),'Success','modal')
            end
            app.IdleLabel.Text = 'Verification completed';
            end
        end

        % Button pushed function: ViewaccountButton
        function ViewaccountButtonPushed(app, event)
            app.NameTextArea.Value = '';
            app.InfoTextArea.Value = '';
            [FileName, PathName] = uigetfile('*.mat','Select an account...', './template-database/');
            load([PathName, FileName]);
            app.NameTextArea.Value = name;
            app.InfoTextArea.Value = exinfo;
            app.IdleLabel.Text = sprintf('Account_ID = %s', FileName(1:end-4));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IrisRecognition = uifigure;
            app.IrisRecognition.Name = 'IRIS Recognition';
            app.IrisRecognition.CloseRequestFcn = createCallbackFcn(app, @IrisRecognitionCloseRequest, true);
            app.ImageselectButton = uibutton(app.IrisRecognition, 'push');
            app.ImageselectButton.ButtonPushedFcn = createCallbackFcn(app, @ImageselectButtonPushed, true);
            app.ImageselectButton.Text = 'Image select...';
            app.NameTextAreaLabel = uilabel(app.IrisRecognition);
            app.NameTextAreaLabel.Text = 'Name';
            app.NameTextArea = uitextarea(app.IrisRecognition);
            app.InfoTextAreaLabel = uilabel(app.IrisRecognition);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.IrisRecognition);
            app.ViewaccountButton = uibutton(app.IrisRecognition, 'push');
            app.ViewaccountButton.ButtonPushedFcn = createCallbackFcn(app, @ViewaccountButtonPushed, true);
            app.ViewaccountButton.Text = 'View account...';
            app.EnrollButton = uibutton(app.IrisRecognition, 'push');
            app.EnrollButton.ButtonPushedFcn = createCallbackFcn(app, @EnrollButtonPushed, true);
            app.EnrollButton.Text = 'Enroll';
            app.VerifyButton = uibutton(app.IrisRecognition, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.StatusLabel = uilabel(app.IrisRecognition);
            app.StatusLabel.Text = 'Status:';
            app.IdleLabel = uilabel(app.IrisRecognition);
            app.IdleLabel.Text = 'Idle';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_iris_recognization

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IrisRecognition)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IrisRecognition)
        end
    end
end